classdef CircleOfWillis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes6                  matlab.ui.control.UIAxes
        SliceSliderLabel         matlab.ui.control.Label
        SliceSlider              matlab.ui.control.Slider
        ReadNIFTIFileButton      matlab.ui.control.Button
        ReadProcessedFileButton  matlab.ui.control.Button
        brightnessSliderLabel    matlab.ui.control.Label
        brightnessSlider         matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadNIFTIFileButton
        function ReadNIFTIFileButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.nii');
            currFileDataName    = fullfile(pathname,filename);
            currFileData        = niftiread(currFileDataName);
            vasculature         = segment_CircleOfWillis(currFileData,0);
            %MIP_a               = findobj('tag','MaxIntProj_axial');
            imshow(vasculature.maxIntensity2,'Parent',app.UIAxes6);
            imshow(vasculature.maxIntensityS,'Parent',app.UIAxes4);
            imshow(vasculature.maxIntensityC,'Parent',app.UIAxes5);
            %colormap gray
            app.SliceSlider.Limits = [1 vasculature.numSlices];
            app.SliceSlider.Value = 1;
            app.brightnessSlider.Limits = [0 2];
            app.brightnessSlider.Value = 1;
            imagesc(vasculature.rawData(:,:,app.SliceSlider.Value),'Parent',app.UIAxes2);
            grayC = repmat(linspace(0,1,256)',[1 3]);
            app.UIAxes2.Colormap = grayC;
            app.UIAxes2.YLim     = [1 vasculature.rows];
            app.UIAxes2.XLim     = [1 vasculature.cols];
            app.UIAxes2.UserData =   vasculature;
                        % 3D surfaces of the vasculature
            se = strel('sphere',2);
            if isfield(vasculature,'mainRegions')
                fColor = [1 0 0; 0 0 1; 0 1 1;1 1 0;1 0 1;0.5 0.5 1;0.5 1 0.5];
                [f,v]   = isosurface(vasculature.vessels,0.05);
                [f2,v2] = isosurface(vasculature.skeleton,0.05);
                [f3,v3] = isosurface(imdilate(vasculature.branchPoints,se),0.05);
                
                % hPatch  = patch('Faces',f,'Vertices',v,'edgecolor','none','facecolor',0.7*[1 1 1]);
                hPatch2 = patch('Parent',app.UIAxes,'Faces',f2,'Vertices',v2,'edgecolor','none','facecolor','k');
                hPatch3 = patch('Parent',app.UIAxes,'Faces',f3,'Vertices',v3,'edgecolor','none','facecolor','g');
                hPatch.FaceAlpha = 0.2;
                k2=0;
                for k=1:vasculature.totRegions
                    [fr,vr]= isosurface(vasculature.vesselsL==k,0.05);
                    if isempty(intersect(k,vasculature.mainRegions))
                        hPatch4 = patch('Parent',app.UIAxes,'Faces',fr,'Vertices',vr,'edgecolor','none','facecolor',0.7*[1 1 1]);
                    else
                        k2=k2+1;
                        hPatch4 = patch('Parent',app.UIAxes,'Faces',fr,'Vertices',vr,'edgecolor','none','facecolor',fColor(k2,:));
                        
                    end
                    hPatch4.FaceAlpha = 0.2;
                end


            else
                [f,v]   = isosurface(vasculature.vessels,0.05);
                [f2,v2] = isosurface(vasculature.skeleton,0.05);
                [f3,v3] = isosurface(imdilate(vasculature.branchPoints,se),0.05);
                
                %subplot(122)
                hPatch = patch('Parent',app.UIAxes,'Faces',f,'Vertices',v,'edgecolor','none','facecolor','r');
                hPatch2 = patch('Parent',app.UIAxes,'Faces',f2,'Vertices',v2,'edgecolor','none','facecolor','k');
                hPatch3 = patch('Parent',app.UIAxes,'Faces',f3,'Vertices',v3,'edgecolor','none','facecolor','g');
            end
            
            camlight(app.UIAxes)
            %rotate3d(app.UIAxes)
            grid(app.UIAxes)
            %lighting phong
            axis(app.UIAxes, 'tight');
            rotate3d(app.UIAxes,'on');


        end

        % Button pushed function: ReadProcessedFileButton
        function ReadProcessedFileButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            currFileDataName    = fullfile(pathname,filename);
            currFileData        = load(currFileDataName);
            vasculature         = currFileData.vasculature;
            %MIP_a               = findobj('tag','MaxIntProj_axial');
            imshow(vasculature.maxIntensity2,'Parent',app.UIAxes6);
            imshow(vasculature.maxIntensityS,'Parent',app.UIAxes4);
            imshow(vasculature.maxIntensityC,'Parent',app.UIAxes5);
            %colormap gray
            app.SliceSlider.Limits = [1 vasculature.numSlices];
            app.SliceSlider.Value = 1;
            app.brightnessSlider.Limits = [0 2];
            app.brightnessSlider.Value = 1;
            imagesc(vasculature.rawData(:,:,app.SliceSlider.Value),'Parent',app.UIAxes2);
            grayC = repmat(linspace(0,1,256)',[1 3]);
            app.UIAxes2.Colormap = grayC;
            app.UIAxes2.YLim     = [1 vasculature.rows];
            app.UIAxes2.XLim     = [1 vasculature.cols];
            app.UIAxes2.UserData =   vasculature;
            
          
            % 3D surfaces of the vasculature
            se = strel('sphere',2);
            if isfield(vasculature,'mainRegions')
                fColor = [1 0 0; 0 0 1; 0 1 1;1 1 0;1 0 1;0.5 0.5 1;0.5 1 0.5];
                [f,v]   = isosurface(vasculature.vessels,0.05);
                [f2,v2] = isosurface(vasculature.skeleton,0.05);
                [f3,v3] = isosurface(imdilate(vasculature.branchPoints,se),0.05);
                
                % hPatch  = patch('Faces',f,'Vertices',v,'edgecolor','none','facecolor',0.7*[1 1 1]);
                hPatch2 = patch('Parent',app.UIAxes,'Faces',f2,'Vertices',v2,'edgecolor','none','facecolor','k');
                hPatch3 = patch('Parent',app.UIAxes,'Faces',f3,'Vertices',v3,'edgecolor','none','facecolor','g');
                hPatch.FaceAlpha = 0.2;
                k2=0;
                for k=1:vasculature.totRegions
                    [fr,vr]= isosurface(vasculature.vesselsL==k,0.05);
                    if isempty(intersect(k,vasculature.mainRegions))
                        hPatch4 = patch('Parent',app.UIAxes,'Faces',fr,'Vertices',vr,'edgecolor','none','facecolor',0.7*[1 1 1]);
                    else
                        k2=k2+1;
                        hPatch4 = patch('Parent',app.UIAxes,'Faces',fr,'Vertices',vr,'edgecolor','none','facecolor',fColor(k2,:));
                        
                    end
                    hPatch4.FaceAlpha = 0.2;
                end


            else
                [f,v]   = isosurface(vasculature.vessels,0.05);
                [f2,v2] = isosurface(vasculature.skeleton,0.05);
                [f3,v3] = isosurface(imdilate(vasculature.branchPoints,se),0.05);
                
                %subplot(122)
                hPatch = patch('Parent',app.UIAxes,'Faces',f,'Vertices',v,'edgecolor','none','facecolor','r');
                hPatch2 = patch('Parent',app.UIAxes,'Faces',f2,'Vertices',v2,'edgecolor','none','facecolor','k');
                hPatch3 = patch('Parent',app.UIAxes,'Faces',f3,'Vertices',v3,'edgecolor','none','facecolor','g');
            end
            
            camlight(app.UIAxes)
            %rotate3d(app.UIAxes)
            grid(app.UIAxes)
            %lighting phong
            axis(app.UIAxes, 'tight');
            rotate3d(app.UIAxes,'on');
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = round(event.Value);
            vasculature   = app.UIAxes2.UserData;
            imagesc(vasculature.rawData(:,:,changingValue),'Parent',app.UIAxes2);
        end

        % Value changing function: brightnessSlider
        function brightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            grayC = (repmat(linspace(0,1,256)',[1 3])).^(changingValue);
            app.UIAxes2.Colormap = grayC;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'vasculature')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Axial slice')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Max Intesity Cor')
            xlabel(app.UIAxes4, ' ')
            ylabel(app.UIAxes4, '')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Max Intensity Sag')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Max Intensity Axial')
            xlabel(app.UIAxes6, ' ')
            ylabel(app.UIAxes6, '')
            zlabel(app.UIAxes6, 'Z')
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.ReadNIFTIFileButton = uibutton(app.UIFigure, 'push');
            app.ReadNIFTIFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNIFTIFileButtonPushed, true);
            app.ReadNIFTIFileButton.Text = 'Read NIFTI File';
            app.ReadProcessedFileButton = uibutton(app.UIFigure, 'push');
            app.ReadProcessedFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadProcessedFileButtonPushed, true);
            app.ReadProcessedFileButton.Text = 'Read Processed File';
            app.brightnessSliderLabel = uilabel(app.UIFigure);
            app.brightnessSliderLabel.Text = 'brightness';
            app.brightnessSlider = uislider(app.UIFigure);
            app.brightnessSlider.ValueChangingFcn = createCallbackFcn(app, @brightnessSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CircleOfWillis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end